classdef OceanFluxBudget < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OceanFluxBudgetToolUIFigure   matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        ComponentPanel                matlab.ui.container.Panel
        ComponentDropDown             matlab.ui.control.DropDown
        DisplayOptionsPanel           matlab.ui.container.Panel
        ShowbudgetcomponentsSwitch    matlab.ui.control.Switch
        ShowbudgetcomponentsLabel     matlab.ui.control.Label
        FixaxesSwitch                 matlab.ui.control.Switch
        FixaxesSwitchLabel            matlab.ui.control.Label
        ShowtrendSwitch               matlab.ui.control.Switch
        ShowmaxminSwitch_2Label_2     matlab.ui.control.Label
        ShowmaxminSwitch              matlab.ui.control.Switch
        ShowmaxminSwitch_2Label       matlab.ui.control.Label
        ShowcorrelationsSwitch        matlab.ui.control.Switch
        ShowcorrelationsSwitchLabel   matlab.ui.control.Label
        ReferenceValuesPanel          matlab.ui.container.Panel
        ReferenceTemperatureCSpinner  matlab.ui.control.Spinner
        ReferenceTemperatureCSpinnerLabel  matlab.ui.control.Label
        ReferenceSalinitygkgSpinner   matlab.ui.control.Spinner
        ReferenceSalinitygkgSpinnerLabel  matlab.ui.control.Label
        PropertyTimeseriesPanel       matlab.ui.container.Panel
        LengthoftimeseriesEditField   matlab.ui.control.NumericEditField
        LengthoftimeseriesEditFieldLabel  matlab.ui.control.Label
        EnddateDatePicker             matlab.ui.control.DatePicker
        EnddateDatePickerLabel        matlab.ui.control.Label
        StartdateDatePicker           matlab.ui.control.DatePicker
        StartdateDatePickerLabel      matlab.ui.control.Label
        BalancemassSwitch             matlab.ui.control.Switch
        BalancemassSwitchLabel        matlab.ui.control.Label
        ReinitializeButton            matlab.ui.control.Button
        CenterPanel                   matlab.ui.container.Panel
        ComponentLabel                matlab.ui.control.Label
        CorrelationTable              matlab.ui.control.Table
        FluxesLabel                   matlab.ui.control.Label
        PropertiesLabel               matlab.ui.control.Label
        HeatFluxFigure                matlab.ui.control.UIAxes
        SaltFluxFigure                matlab.ui.control.UIAxes
        VolFluxFigure                 matlab.ui.control.UIAxes
        LFCFluxFigure                 matlab.ui.control.UIAxes
        MassFluxFigure                matlab.ui.control.UIAxes
        DensityFigure                 matlab.ui.control.UIAxes
        SalinityFigure                matlab.ui.control.UIAxes
        TemperatureFigure             matlab.ui.control.UIAxes
        SpeedFigure                   matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        ControlVolumeLabel            matlab.ui.control.Label
        PropertiesLabel_2             matlab.ui.control.Label
        BudgetsLabel                  matlab.ui.control.Label
        AvgTemperatureFigure          matlab.ui.control.UIAxes
        AvgSalinityFigure             matlab.ui.control.UIAxes
        LFCBudgetFigure               matlab.ui.control.UIAxes
        HeatBudgetFigure              matlab.ui.control.UIAxes
        SaltBudgetFigure              matlab.ui.control.UIAxes
        VolBudgetFigure               matlab.ui.control.UIAxes
        MassBudgetFigure              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = public)
        Components      % Structure containing the components structure.
        budgets         % Structure containing the budgets structure.
        CtrlVol         % Structure containing the CtrlVol structure.
        DataParams      % Structure containing the DataParams structure.
        FluxParams      % Structure containing the FluxParams structure.
        PlotParams      % Structure containing the PlotParams structure.
        this_component  % Name of component being displayed.
    end

    methods (Access = private)
        function UpdateComponentPropertiesFigure(app)
            % Plot this component's raw timeseries
            plot(app.SpeedFigure,      app.DataParams.time,app.Components.(app.this_component).normal_speed,'LineWidth',3,'Color',app.PlotParams.(app.this_component).color) ;
            plot(app.TemperatureFigure,app.DataParams.time,app.Components.(app.this_component).temperature, 'LineWidth',3,'Color',app.PlotParams.(app.this_component).color) ;
            plot(app.SalinityFigure,   app.DataParams.time,app.Components.(app.this_component).salinity,    'LineWidth',3,'Color',app.PlotParams.(app.this_component).color) ;
            plot(app.DensityFigure,    app.DataParams.time,app.Components.(app.this_component).density,     'LineWidth',3,'Color',app.PlotParams.(app.this_component).color) ;
        end

        function UpdateFluxesFigure(app)
            % Plot this component's computed timeseries
            secs_in_a_yr = 86400*365.24 ;
            plot(app.MassFluxFigure,app.DataParams.time,app.Components.(app.this_component).mass_flux./1e9,              'LineWidth',3,'Color',app.PlotParams.(app.this_component).color) ;
            plot(app.VolFluxFigure, app.DataParams.time,app.Components.(app.this_component).vol_flux./1e6,               'LineWidth',3,'Color',app.PlotParams.(app.this_component).color) ;
            plot(app.SaltFluxFigure,app.DataParams.time,app.Components.(app.this_component).salt_flux./1e6,              'LineWidth',3,'Color',app.PlotParams.(app.this_component).color) ;
            plot(app.HeatFluxFigure,app.DataParams.time,app.Components.(app.this_component).heat_flux./1e12,             'LineWidth',3,'Color',app.PlotParams.(app.this_component).color) ;
            plot(app.LFCFluxFigure, app.DataParams.time,app.Components.(app.this_component).LFC_flux.*(secs_in_a_yr/1e9),'LineWidth',3,'Color',app.PlotParams.(app.this_component).color) ;

            % Add correlation numbers
            if(strcmp(app.PlotParams.correlationFlag,'On'))
                app.CorrelationTable.Data = [ ...
                    app.Components.(app.this_component).salt_flux_correl.vol_flux.r app.Components.(app.this_component).salt_flux_correl.salinity.r    ; ...
                    app.Components.(app.this_component).heat_flux_correl.vol_flux.r app.Components.(app.this_component).heat_flux_correl.temperature.r ; ...
                    app.Components.(app.this_component).LFC_flux_correl.vol_flux.r  app.Components.(app.this_component).LFC_flux_correl.salinity.r ] ;
            end % if
        end

        function UpdateBudgetsFigure(app)
            % Plot budget computed timeseries
            plotBudgetFigure(app,'MassBudgetFigure','mass',1) ;
            plotBudgetFigure(app,'VolBudgetFigure','volume',1e9) ;
            plotBudgetFigure(app,'SaltBudgetFigure','salt',1e15) ;
            plotBudgetFigure(app,'HeatBudgetFigure','heat',1) ;
            plotBudgetFigure(app,'LFCBudgetFigure','LFC',1e9) ;

            function plotBudgetFigure(app,han,var,fac)
                component_names = fieldnames(app.budgets.(var)) ;
                Ns = numel(component_names) ;
                l = zeros(1,Ns) ;
                l(1) = plot(app.(han),app.DataParams.time,app.budgets.(var).total./fac,'LineWidth',3,'Color','g','DisplayName','Total') ;
                tmp = get(l(1)) ;
                tmp.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow('Total','') ;
                if(strcmp(app.PlotParams.BudgetComponentsFlag,'On'))
                    hold(app.(han),'on') ;
                    for ss = 2:Ns
                        l(ss) = plot(app.(han),app.DataParams.time,app.budgets.(var).(component_names{ss})./fac,...
                            'LineWidth',2,'Color',app.PlotParams.(component_names{ss}).color,'DisplayName',app.Components.(component_names{ss}).name) ;
                        tmp = get(l(ss)) ;
                        tmp.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow(app.Components.(component_names{ss}).name,'') ;
                    end % ss
                    hold(app.(han),'off') ;
                    if(strcmp(var,'mass'))
                        tmp = app.(han).Position ;
                        legend(app.(han),l,'location','eastoutside','NumColumns',1);
                        app.(han).Position = tmp ;
                    end % if
                else
                    legend(app.(han),'Off') ;
                end % if
            end
        end

        function UpdateCtrlVolPropertiesFigure(app)
            plot(app.AvgTemperatureFigure,app.DataParams.time,app.CtrlVol.temp, 'LineWidth',3,'Color','g') ;
            plot(app.AvgSalinityFigure,   app.DataParams.time,app.CtrlVol.sal,  'LineWidth',3,'Color','g') ;
        end

        function UpdateAll(app)
            app.Components = Updatecomponents(app.FluxParams,app.DataParams,app.Components) ;
            app.budgets = ComputeBudgets(app.Components,app.AirSeaHeatFlux,app.DataParams) ;
            app.CtrlVol = ComputeCtrlVolProperties(app.budgets,app.DataParams) ;

            UpdateComponentPropertiesFigure(app) ;
            UpdateFluxesFigure(app) ;
            UpdateBudgetsFigure(app) ;
            UpdateCtrlVolPropertiesFigure(app) ;

            names = fieldnames(app) ;
            inds = find(contains(names,'Figure')) ;
            for ii = 2:length(inds)
                AdjustFigure(app,names{inds(ii)}) ;
            end % ii
            app.CorrelationTable.Data = [ ...
                app.Components.(app.this_component).salt_flux_correl.vol_flux.r app.Components.(app.this_component).salt_flux_correl.salinity.r    ; ...
                app.Components.(app.this_component).heat_flux_correl.vol_flux.r app.Components.(app.this_component).heat_flux_correl.temperature.r ; ...
                app.Components.(app.this_component).LFC_flux_correl.vol_flux.r  app.Components.(app.this_component).LFC_flux_correl.salinity.r ] ;

            tmp = [ ...
                app.Components.(app.this_component).salt_flux_correl.vol_flux.p app.Components.(app.this_component).salt_flux_correl.salinity.p    ; ...
                app.Components.(app.this_component).heat_flux_correl.vol_flux.p app.Components.(app.this_component).heat_flux_correl.temperature.p ; ...
                app.Components.(app.this_component).LFC_flux_correl.vol_flux.p  app.Components.(app.this_component).LFC_flux_correl.salinity.p ] ;
            addStyle(app.CorrelationTable,uistyle('FontWeight','normal')) ;
            [row,col] = find(tmp < app.PlotParams.correlationTable.significance) ;
            addStyle(app.CorrelationTable,uistyle('FontWeight','bold'),'cell',[row,col]) ;
        end

        function StartPlots(app,han)
            app.(han).PositionConstraint = 'innerposition';
            tmp = app.(han).InnerPosition ;
            app.(han).InnerPosition = [tmp(1:2) app.PlotParams.width app.PlotParams.height] ;
            app.(han).XGrid              = 'On' ;
            app.(han).YGrid              = 'On' ;
            app.(han).Box                = 'On' ;
        end

        function AdjustFigure(app,han)
            app.(han).XLim               = [app.DataParams.start_date app.DataParams.end_date] ;

            % Let axes auto-adjust, or fix them to default values
            if(strcmp(app.PlotParams.FixaxesFlag,'Off'))
                app.(han).YLimMode = 'auto' ;
            else
                app.(han).YLimMode = 'manual' ;
            end % if

            % Add maximum/minimum lines to plot
            if(strcmp(app.PlotParams.extremaFlag,'On'))
                xdata = app.(han).Children(end).XData ;
                ydata = app.(han).Children(end).YData ;
                [~,max_ind] = max(ydata) ;
                [~,min_ind] = min(ydata) ;
                ylimits = app.(han).YLim ;
                hold(app.(han),'On') ;
                plot(app.(han),[xdata(max_ind) xdata(max_ind)],ylimits,'-','Color','red' ,'DisplayName','Max.','LineWidth',2) ;
                plot(app.(han),[xdata(min_ind) xdata(min_ind)],ylimits,'-','Color','blue','DisplayName','Min,','LineWidth',2) ;
                app.(han).YLim = ylimits ;
                hold(app.(han),'Off') ;
            end % if

            % Add trend lines to plot
            if(strcmp(app.PlotParams.trendFlag,'On'))
                xdata = app.(han).Children(end).XData ;
                ydata = app.(han).Children(end).YData ;
                hold(app.(han),'On') ;
                if(ydata(end) >= ydata(1))
                    trend_color = 'red' ;
                else
                    trend_color = 'blue' ;
                end % if
                plot(app.(han),[xdata(1) xdata(end)],[ydata(1) ydata(end)],'--','Color',trend_color,'DisplayName','Trend','LineWidth',3) ;
                hold(app.(han),'Off') ;
            end % if

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            rng(1) ;    % Random number generator: This makes random numbers reproducible.
            app.this_component     = 'WestFramcomponent' ;
            app.DataParams      = InitializeDataParameters(30) ;
            app.FluxParams      = InitializeFluxParameters ;
            app.Components         = Initializecomponents(app.DataParams,app.FluxParams) ;
            app.AirSeaHeatFlux  = InitializeAirSeaHeatFlux(app.DataParams) ;
            app.PlotParams      = InitializePlotParameters ;

            app.CorrelationTable.Visible   = 'Off';
            addStyle(app.CorrelationTable,uistyle('HorizontalAlignment','center'));
            app.PlotParams.correlationTable.significance = 1e-3 ;           % Level of correlation significance set here.

            names = fieldnames(app) ;
            inds = find(contains(names,'Figure')) ;
            for ii = 2:length(inds)
                StartPlots(app,names{inds(ii)}) ;
            end % ii

            names = app.ComponentDropDown.ItemsData ;
            for ii = 1:length(names)
                s = uistyle(FontColor = app.PlotParams.(names{ii}).color) ;
                addStyle(app.ComponentDropDown,s,"item",ii)
            end % ii
            app.PropertiesLabel.FontColor = app.PlotParams.(app.this_component).color ;
            app.FluxesLabel.FontColor     = app.PlotParams.(app.this_component).color ;
            app.ComponentLabel.FontColor    = app.PlotParams.(app.this_component).color ;

            UpdateAll(app) ;
        end

        % Button pushed function: ReinitializeButton
        function ReinitializeButtonPushed(app, event)
            app.Components = Initializecomponents(app.DataParams,app.FluxParams) ;
            app.PlotParams.FixaxesFlag = 'Off';
            app.FixaxesSwitch.Value = 'Off';
            UpdateAll(app) ;
        end

        % Value changed function: ComponentDropDown
        function ComponentDropDownValueChanged(app, event)
            app.this_component               = app.ComponentDropDown.Value;
            app.PlotParams.FixaxesFlag    = 'Off';
            app.FixaxesSwitch.Value       = 'Off';
            app.ComponentDropDown.FontColor = app.PlotParams.(app.this_component).color ;
            app.FluxesLabel.FontColor     = app.PlotParams.(app.this_component).color ;
            app.PropertiesLabel.FontColor = app.PlotParams.(app.this_component).color ;
            UpdateAll(app) ;
        end

        % Value changed function: BalancemassSwitch
        function BalancemassSwitchValueChanged(app, event)
            app.DataParams.massBalance = app.BalancemassSwitch.Value;
            app.Components = Initializecomponents(app.DataParams,app.FluxParams) ;
            app.PlotParams.FixaxesFlag = 'Off';
            app.FixaxesSwitch.Value = 'Off';
            UpdateAll(app) ;
        end

        % Value changed function: EnddateDatePicker
        function EnddateDatePickerValueChanged(app, event)
            app.DataParams.end_date = app.EnddateDatePicker.Value;
            app.Components = Initializecomponents(app.DataParams,app.FluxParams) ;
            UpdateAll(app) ;
        end

        % Value changed function: StartdateDatePicker
        function StartdateDatePickerValueChanged(app, event)
            app.DataParams.start_date = app.StartdateDatePicker.Value;
            app.Components = Initializecomponents(app.DataParams,app.FluxParams) ;
            UpdateAll(app) ;
        end

        % Value changed function: LengthoftimeseriesEditField
        function LengthoftimeseriesEditFieldValueChanged(app, event)
            app.DataParams.N = app.LengthoftimeseriesEditField.Value;
            app.Components = Initializecomponents(app.DataParams,app.FluxParams) ;
            UpdateAll(app) ;
        end

        % Value changed function: ReferenceTemperatureCSpinner
        function ReferenceTemperatureCSpinnerValueChanged(app, event)
            app.FluxParams.T_ref = app.ReferenceTemperatureCSpinner.Value;
            UpdateAll(app) ;
        end

        % Value changing function: ReferenceTemperatureCSpinner
        function ReferenceTemperatureCSpinnerValueChanging(app, event)
            app.FluxParams.T_ref = event.Value;
            UpdateAll(app) ;
        end

        % Value changed function: ReferenceSalinitygkgSpinner
        function ReferenceSalinitygkgSpinnerValueChanged(app, event)
            app.FluxParams.S_ref = app.ReferenceSalinitygkgSpinner.Value;
            UpdateAll(app) ;
        end

        % Value changing function: ReferenceSalinitygkgSpinner
        function ReferenceSalinitygkgSpinnerValueChanging(app, event)
            app.FluxParams.S_ref = event.Value;
            UpdateAll(app) ;
        end

        % Value changed function: ShowcorrelationsSwitch
        function ShowcorrelationsSwitchValueChanged(app, event)
            app.PlotParams.correlationFlag = app.ShowcorrelationsSwitch.Value;
            if(strcmp(app.PlotParams.correlationFlag,'On'))
                app.CorrelationTable.Visible = 'on';
            elseif(strcmp(app.PlotParams.correlationFlag,'Off'))
                app.CorrelationTable.Visible = 'off';
            end % if
        end

        % Value changed function: ShowmaxminSwitch
        function ShowmaxminSwitchValueChanged(app, event)
            app.PlotParams.extremaFlag = app.ShowmaxminSwitch.Value;
            UpdateAll(app) ;
        end

        % Value changed function: ShowtrendSwitch
        function ShowtrendSwitchValueChanged(app, event)
            app.PlotParams.trendFlag = app.ShowtrendSwitch.Value;
            UpdateAll(app) ;
        end

        % Value changed function: FixaxesSwitch
        function FixaxesSwitchValueChanged(app, event)
            app.PlotParams.FixaxesFlag = app.FixaxesSwitch.Value;
            UpdateAll(app) ;
        end

        % Value changed function: ShowbudgetcomponentsSwitch
        function ShowbudgetcomponentsSwitchValueChanged(app, event)
            app.PlotParams.BudgetComponentsFlag = app.ShowbudgetComponentsSwitch.Value;
            UpdateAll(app) ;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.OceanFluxBudgetToolUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {1020, 1020, 1020};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {1020, 1020};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {365, '1x', 706};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OceanFluxBudgetToolUIFigure = uifigure('Visible', 'off');
            app.OceanFluxBudgetToolUIFigure.Name = 'OceanFluxBudget Tool';
            app.OceanFluxBudgetToolUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.OceanFluxBudgetToolUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PropertyTimeseriesPanel = uipanel(app.LeftPanel);
            app.PropertyTimeseriesPanel.Title = 'Property Timeseries';
            app.ReinitializeButton = uibutton(app.PropertyTimeseriesPanel, 'push');
            app.ReinitializeButton.ButtonPushedFcn = createCallbackFcn(app, @ReinitializeButtonPushed, true);
            app.ReinitializeButton.Text = 'Reinitialize';
            app.BalancemassSwitchLabel = uilabel(app.PropertyTimeseriesPanel);
            app.BalancemassSwitchLabel.Text = 'Balance mass';
            app.BalancemassSwitch = uiswitch(app.PropertyTimeseriesPanel, 'slider');
            app.BalancemassSwitch.ValueChangedFcn = createCallbackFcn(app, @BalancemassSwitchValueChanged, true);
            app.BalancemassSwitch.Value = 'On';
            app.StartdateDatePickerLabel = uilabel(app.PropertyTimeseriesPanel);
            app.StartdateDatePickerLabel.Text = 'Start date';
            app.StartdateDatePicker = uidatepicker(app.PropertyTimeseriesPanel);
            app.StartdateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartdateDatePickerValueChanged, true);
            app.StartdateDatePicker.Enable = 'off';
            app.StartdateDatePicker.Placeholder = '01-JAN-1990';
            app.StartdateDatePicker.Value = datetime([1990 1 1]);
            app.EnddateDatePickerLabel = uilabel(app.PropertyTimeseriesPanel);
            app.EnddateDatePickerLabel.Text = 'End date';
            app.EnddateDatePicker = uidatepicker(app.PropertyTimeseriesPanel);
            app.EnddateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EnddateDatePickerValueChanged, true);
            app.EnddateDatePicker.Enable = 'off';
            app.EnddateDatePicker.Placeholder = '31-DEC-2020';
            app.EnddateDatePicker.Value = datetime([2020 12 31]);
            app.LengthoftimeseriesEditFieldLabel = uilabel(app.PropertyTimeseriesPanel);
            app.LengthoftimeseriesEditFieldLabel.Text = 'Length of timeseries';
            app.LengthoftimeseriesEditField = uieditfield(app.PropertyTimeseriesPanel, 'numeric');
            app.LengthoftimeseriesEditField.Limits = [0 256];
            app.LengthoftimeseriesEditField.RoundFractionalValues = 'on';
            app.LengthoftimeseriesEditField.ValueDisplayFormat = '%4.0f';
            app.LengthoftimeseriesEditField.ValueChangedFcn = createCallbackFcn(app, @LengthoftimeseriesEditFieldValueChanged, true);
            app.LengthoftimeseriesEditField.Enable = 'off';
            app.LengthoftimeseriesEditField.Value = 30;
            app.ReferenceValuesPanel = uipanel(app.LeftPanel);
            app.ReferenceValuesPanel.Title = 'Reference Values';
            app.ReferenceSalinitygkgSpinnerLabel = uilabel(app.ReferenceValuesPanel);
            app.ReferenceSalinitygkgSpinnerLabel.Text = 'Reference Salinity [g/kg]';
            app.ReferenceSalinitygkgSpinner = uispinner(app.ReferenceValuesPanel);
            app.ReferenceSalinitygkgSpinner.Step = 0.1;
            app.ReferenceSalinitygkgSpinner.ValueChangingFcn = createCallbackFcn(app, @ReferenceSalinitygkgSpinnerValueChanging, true);
            app.ReferenceSalinitygkgSpinner.Limits = [0 40];
            app.ReferenceSalinitygkgSpinner.ValueChangedFcn = createCallbackFcn(app, @ReferenceSalinitygkgSpinnerValueChanged, true);
            app.ReferenceSalinitygkgSpinner.Value = 34.8;
            app.ReferenceTemperatureCSpinnerLabel = uilabel(app.ReferenceValuesPanel);
            app.ReferenceTemperatureCSpinnerLabel.Text = 'Reference Temperature [C]';
            app.ReferenceTemperatureCSpinner = uispinner(app.ReferenceValuesPanel);
            app.ReferenceTemperatureCSpinner.Step = 0.25;
            app.ReferenceTemperatureCSpinner.ValueChangingFcn = createCallbackFcn(app, @ReferenceTemperatureCSpinnerValueChanging, true);
            app.ReferenceTemperatureCSpinner.Limits = [-2 10];
            app.ReferenceTemperatureCSpinner.ValueChangedFcn = createCallbackFcn(app, @ReferenceTemperatureCSpinnerValueChanged, true);
            app.DisplayOptionsPanel = uipanel(app.LeftPanel);
            app.DisplayOptionsPanel.Title = 'Display Options';
            app.ShowcorrelationsSwitchLabel = uilabel(app.DisplayOptionsPanel);
            app.ShowcorrelationsSwitchLabel.Text = 'Show correlations';
            app.ShowcorrelationsSwitch = uiswitch(app.DisplayOptionsPanel, 'slider');
            app.ShowcorrelationsSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowcorrelationsSwitchValueChanged, true);
            app.ShowmaxminSwitch_2Label = uilabel(app.DisplayOptionsPanel);
            app.ShowmaxminSwitch_2Label.Text = 'Show max/min';
            app.ShowmaxminSwitch = uiswitch(app.DisplayOptionsPanel, 'slider');
            app.ShowmaxminSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowmaxminSwitchValueChanged, true);
            app.ShowmaxminSwitch_2Label_2 = uilabel(app.DisplayOptionsPanel);
            app.ShowmaxminSwitch_2Label_2.Text = 'Show trend';
            app.ShowtrendSwitch = uiswitch(app.DisplayOptionsPanel, 'slider');
            app.ShowtrendSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowtrendSwitchValueChanged, true);
            app.FixaxesSwitchLabel = uilabel(app.DisplayOptionsPanel);
            app.FixaxesSwitchLabel.Text = 'Fix axes';
            app.FixaxesSwitch = uiswitch(app.DisplayOptionsPanel, 'slider');
            app.FixaxesSwitch.ValueChangedFcn = createCallbackFcn(app, @FixaxesSwitchValueChanged, true);
            app.ShowbudgetcomponentsLabel = uilabel(app.DisplayOptionsPanel);
            app.ShowbudgetcomponentsLabel.Text = {'Show budget '; 'components'};
            app.ShowbudgetcomponentsSwitch = uiswitch(app.DisplayOptionsPanel, 'slider');
            app.ShowbudgetcomponentsSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowbudgetcomponentsSwitchValueChanged, true);
            app.ComponentPanel = uipanel(app.LeftPanel);
            app.ComponentPanel.Title = 'Component';
            app.ComponentDropDown = uidropdown(app.ComponentPanel);
            app.ComponentDropDown.Items = {'West Fram Strait (EGC)', 'East Fram Strait (WSC)', 'BSO', 'Davis Strait', 'Bering Strait', 'R + P - E', 'Air/sea heat exchange'};
            app.ComponentDropDown.ItemsData = {'WestFramStrait', 'EastFramStrait', 'BSO', 'DavisStrait', 'BeringStrait', 'RpPmEStrait', 'AirSeaHeatFlux'};
            app.ComponentDropDown.ValueChangedFcn = createCallbackFcn(app, @ComponentDropDownValueChanged, true);
            app.ComponentDropDown.Value = 'WestFramStrait';
            app.CenterPanel = uipanel(app.GridLayout);
            app.SpeedFigure = uiaxes(app.CenterPanel);
            title(app.SpeedFigure, 'Speed [m/s]')
            ylabel(app.SpeedFigure, 'Speed [m/s]')
            zlabel(app.SpeedFigure, 'Z')
            app.SpeedFigure.YLim = [-0.1 0.1];
            app.SpeedFigure.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.TemperatureFigure = uiaxes(app.CenterPanel);
            title(app.TemperatureFigure, 'Temperature [C]')
            ylabel(app.TemperatureFigure, 'Temperature [C]')
            zlabel(app.TemperatureFigure, 'Z')
            app.TemperatureFigure.YLim = [-2 8];
            app.TemperatureFigure.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.SalinityFigure = uiaxes(app.CenterPanel);
            title(app.SalinityFigure, 'Salinity [g/kg]')
            ylabel(app.SalinityFigure, 'Salinity [g/kg]')
            zlabel(app.SalinityFigure, 'Z')
            app.SalinityFigure.YLim = [30 40];
            app.SalinityFigure.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.DensityFigure = uiaxes(app.CenterPanel);
            title(app.DensityFigure, 'Density [kg/m^3]')
            xlabel(app.DensityFigure, 'Time')
            ylabel(app.DensityFigure, 'Density [kg/m^3]')
            zlabel(app.DensityFigure, 'Z')
            app.DensityFigure.YLim = [1025 1030];
            app.DensityFigure.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.MassFluxFigure = uiaxes(app.CenterPanel);
            title(app.MassFluxFigure, 'Mass flux [Tg/s ~ Sv]')
            ylabel(app.MassFluxFigure, 'Mass flux [Tg/s]')
            zlabel(app.MassFluxFigure, 'Z')
            app.LFCFluxFigure = uiaxes(app.CenterPanel);
            title(app.LFCFluxFigure, 'Liquid FW flux [km^3/yr] (includes sea ice)')
            ylabel(app.LFCFluxFigure, 'Liquid FW flux [km^3/yr]')
            zlabel(app.LFCFluxFigure, 'Z')
            app.LFCFluxFigure.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.VolFluxFigure = uiaxes(app.CenterPanel);
            title(app.VolFluxFigure, 'Volume flux [Sv]')
            ylabel(app.VolFluxFigure, 'Volume flux [Sv]')
            zlabel(app.VolFluxFigure, 'Z')
            app.SaltFluxFigure = uiaxes(app.CenterPanel);
            title(app.SaltFluxFigure, 'Salt flux [Gg/s]')
            ylabel(app.SaltFluxFigure, 'Salt flux [Gg/s]')
            zlabel(app.SaltFluxFigure, 'Z')
            app.HeatFluxFigure = uiaxes(app.CenterPanel);
            title(app.HeatFluxFigure, 'Heat flux [TW]')
            xlabel(app.HeatFluxFigure, 'Time')
            ylabel(app.HeatFluxFigure, 'Heat flux [TW]')
            zlabel(app.HeatFluxFigure, 'Z')
            app.PropertiesLabel = uilabel(app.CenterPanel);
            app.PropertiesLabel.Text = 'Properties';
            app.FluxesLabel = uilabel(app.CenterPanel);
            app.FluxesLabel.Text = 'Fluxes';
            app.CorrelationTable = uitable(app.CenterPanel);
            app.CorrelationTable.ColumnName = {'Speed correlation'; 'Property correlation'};
            app.CorrelationTable.RowName = {'Salt'; 'Heat'; 'Liquid FW'};
            app.ComponentLabel = uilabel(app.CenterPanel);
            app.ComponentLabel.Text = 'Component';
            app.RightPanel = uipanel(app.GridLayout);
            app.MassBudgetFigure = uiaxes(app.RightPanel);
            title(app.MassBudgetFigure, 'Mass [kg]')
            ylabel(app.MassBudgetFigure, 'Mass [kg]')
            zlabel(app.MassBudgetFigure, 'Z')
            app.VolBudgetFigure = uiaxes(app.RightPanel);
            title(app.VolBudgetFigure, 'Volume [km^3]')
            ylabel(app.VolBudgetFigure, 'Volume [km^3]')
            zlabel(app.VolBudgetFigure, 'Z')
            app.SaltBudgetFigure = uiaxes(app.RightPanel);
            title(app.SaltBudgetFigure, 'Salt mass [Pg]')
            ylabel(app.SaltBudgetFigure, 'Salt mass [Pg]')
            zlabel(app.SaltBudgetFigure, 'Z')
            app.HeatBudgetFigure = uiaxes(app.RightPanel);
            title(app.HeatBudgetFigure, 'Heat [J] (excludes atmos. loss)')
            xlabel(app.HeatBudgetFigure, 'Time')
            ylabel(app.HeatBudgetFigure, 'Heat [J]')
            zlabel(app.HeatBudgetFigure, 'Z')
            app.LFCBudgetFigure = uiaxes(app.RightPanel);
            title(app.LFCBudgetFigure, 'Liquid FWC [km^3] (includes sea ice)')
            ylabel(app.LFCBudgetFigure, 'Liquid FWC [km^3]')
            zlabel(app.LFCBudgetFigure, 'Z')
            app.AvgSalinityFigure = uiaxes(app.RightPanel);
            title(app.AvgSalinityFigure, 'Average salinity [g/kg]')
            ylabel(app.AvgSalinityFigure, 'Average salinity [g/kg]')
            zlabel(app.AvgSalinityFigure, 'Z')
            app.AvgTemperatureFigure = uiaxes(app.RightPanel);
            title(app.AvgTemperatureFigure, 'Average temperature [C]')
            xlabel(app.AvgTemperatureFigure, 'Time')
            ylabel(app.AvgTemperatureFigure, 'Average temperature [C]')
            zlabel(app.AvgTemperatureFigure, 'Z')
            app.BudgetsLabel = uilabel(app.RightPanel);
            app.BudgetsLabel.Text = 'Budgets';
            app.PropertiesLabel_2 = uilabel(app.RightPanel);
            app.PropertiesLabel_2.Text = 'Properties';
            app.ControlVolumeLabel = uilabel(app.RightPanel);
            app.ControlVolumeLabel.Text = 'Control Volume';
            % Show the figure after all components are created
            app.OceanFluxBudgetToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OceanFluxBudget

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OceanFluxBudgetToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OceanFluxBudgetToolUIFigure)
        end
    end
end